classdef CCTA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        HOLDGRAPHButton                 matlab.ui.control.Button
        PUMPOFFButton                   matlab.ui.control.Button
        PUMPONButton                    matlab.ui.control.Button
        FlowMonitoringControlPanel      matlab.ui.container.Panel
        AllvaluesinLminLabel            matlab.ui.control.Label
        SVCEditField_2                  matlab.ui.control.NumericEditField
        SVCEditField_2Label             matlab.ui.control.Label
        IVCEditField_2                  matlab.ui.control.NumericEditField
        IVCEditField_2Label             matlab.ui.control.Label
        SetpointLabel_2                 matlab.ui.control.Label
        CurrentLabel_2                  matlab.ui.control.Label
        EditField_5                     matlab.ui.control.NumericEditField
        EditField_4                     matlab.ui.control.NumericEditField
        Label_2                         matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        PressureTab                     matlab.ui.container.Tab
        UIAxes                          matlab.ui.control.UIAxes
        FlowTab                         matlab.ui.container.Tab
        UIAxes_2                        matlab.ui.control.UIAxes
        PressureMonitoringControlPanel  matlab.ui.container.Panel
        LocationLabel                   matlab.ui.control.Label
        AllvaluesinmmHgLabel            matlab.ui.control.Label
        PAEditField                     matlab.ui.control.NumericEditField
        PAEditFieldLabel                matlab.ui.control.Label
        SVCEditField                    matlab.ui.control.NumericEditField
        SVCEditFieldLabel               matlab.ui.control.Label
        IVCEditField                    matlab.ui.control.NumericEditField
        IVCEditFieldLabel               matlab.ui.control.Label
        SetpointLabel                   matlab.ui.control.Label
        CurrentLabel                    matlab.ui.control.Label
        EditField_3                     matlab.ui.control.NumericEditField
        EditField_2                     matlab.ui.control.NumericEditField
        EditField                       matlab.ui.control.NumericEditField
        Label                           matlab.ui.control.Label
        CardiacCatheterizationTestingApparatusLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        % pressure vs. voltage data points for interpolation
        pressure_mmHg = [0, 26.2, 48.7, 74.2, 99.0, 124.5, 150];
        voltage_V = [0.131, 0.850, 1.435, 2.1407, 2.8055, 3.1573, 3.25];

        PLOT_RAW_DATA = true; 
        COM_PORT = serialportlist;
        fs = 100;  % Sampling rate: 100Hz
        ts = 1/fs;
    end
    

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CardiacCatheterizationTestingApparatusLabel = uilabel(app.UIFigure);
            app.CardiacCatheterizationTestingApparatusLabel.Text = 'Cardiac Catheterization Testing Apparatus';
            app.PressureMonitoringControlPanel = uipanel(app.UIFigure);
            app.PressureMonitoringControlPanel.Title = 'Pressure Monitoring & Control';
            app.Label = uilabel(app.PressureMonitoringControlPanel);
            app.Label.Text = '';
            app.EditField = uieditfield(app.PressureMonitoringControlPanel, 'numeric');
            app.EditField_2 = uieditfield(app.PressureMonitoringControlPanel, 'numeric');
            app.EditField_3 = uieditfield(app.PressureMonitoringControlPanel, 'numeric');
            app.CurrentLabel = uilabel(app.PressureMonitoringControlPanel);
            app.CurrentLabel.Text = 'Current';
            app.SetpointLabel = uilabel(app.PressureMonitoringControlPanel);
            app.SetpointLabel.Text = 'Setpoint';
            app.IVCEditFieldLabel = uilabel(app.PressureMonitoringControlPanel);
            app.IVCEditFieldLabel.Text = 'IVC';
            app.IVCEditField = uieditfield(app.PressureMonitoringControlPanel, 'numeric');
            app.SVCEditFieldLabel = uilabel(app.PressureMonitoringControlPanel);
            app.SVCEditFieldLabel.Text = 'SVC';
            app.SVCEditField = uieditfield(app.PressureMonitoringControlPanel, 'numeric');
            app.PAEditFieldLabel = uilabel(app.PressureMonitoringControlPanel);
            app.PAEditFieldLabel.Text = 'PA';
            app.PAEditField = uieditfield(app.PressureMonitoringControlPanel, 'numeric');
            app.AllvaluesinmmHgLabel = uilabel(app.PressureMonitoringControlPanel);
            app.AllvaluesinmmHgLabel.Text = 'All values in mmHg';
            app.LocationLabel = uilabel(app.PressureMonitoringControlPanel);
            app.LocationLabel.Text = 'Location';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PressureTab = uitab(app.TabGroup);
            app.PressureTab.Title = 'Pressure';
            app.UIAxes = uiaxes(app.PressureTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.FlowTab = uitab(app.TabGroup);
            app.FlowTab.Title = 'Flow';
            app.UIAxes_2 = uiaxes(app.FlowTab);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.FlowMonitoringControlPanel = uipanel(app.UIFigure);
            app.FlowMonitoringControlPanel.Title = 'Flow Monitoring & Control';
            app.Label_2 = uilabel(app.FlowMonitoringControlPanel);
            app.Label_2.Text = '';
            app.EditField_4 = uieditfield(app.FlowMonitoringControlPanel, 'numeric');
            app.EditField_5 = uieditfield(app.FlowMonitoringControlPanel, 'numeric');
            app.CurrentLabel_2 = uilabel(app.FlowMonitoringControlPanel);
            app.CurrentLabel_2.Text = 'Current';
            app.SetpointLabel_2 = uilabel(app.FlowMonitoringControlPanel);
            app.SetpointLabel_2.Text = 'Setpoint';
            app.IVCEditField_2Label = uilabel(app.FlowMonitoringControlPanel);
            app.IVCEditField_2Label.Text = 'IVC';
            app.IVCEditField_2 = uieditfield(app.FlowMonitoringControlPanel, 'numeric');
            app.SVCEditField_2Label = uilabel(app.FlowMonitoringControlPanel);
            app.SVCEditField_2Label.Text = 'SVC';
            app.SVCEditField_2 = uieditfield(app.FlowMonitoringControlPanel, 'numeric');
            app.AllvaluesinLminLabel = uilabel(app.FlowMonitoringControlPanel);
            app.AllvaluesinLminLabel.Text = 'All values in L/min';
            app.PUMPONButton = uibutton(app.UIFigure, 'push');
            app.PUMPONButton.Text = 'PUMP ON';
            app.PUMPOFFButton = uibutton(app.UIFigure, 'push');
            app.PUMPOFFButton.Text = 'PUMP OFF';
            app.HOLDGRAPHButton = uibutton(app.UIFigure, 'push');
            app.HOLDGRAPHButton.Text = 'HOLD GRAPH';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CCTA

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end