classdef CCTA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup2                       matlab.ui.container.TabGroup
        MainTab                         matlab.ui.container.Tab
        GraphOptionsPanel               matlab.ui.container.Panel
        GraphOptionsLabel               matlab.ui.control.Label
        ExportDataButton                matlab.ui.control.Button
        ClearDataButton                 matlab.ui.control.Button
        PauseGraphsButton               matlab.ui.control.StateButton
        Panel_5                         matlab.ui.container.Panel
        ConnectionDropDown              matlab.ui.control.DropDown
        COMPortLabel                    matlab.ui.control.Label
        ConnectedLamp                   matlab.ui.control.Lamp
        RefreshConnectionsButton        matlab.ui.control.Button
        ConnectButton                   matlab.ui.control.Button
        FlowGraphPanel                  matlab.ui.container.Panel
        FlowAxesWaitingForConnectionLabel  matlab.ui.control.Label
        FlowAxes                        matlab.ui.control.UIAxes
        PumpControlPanel                matlab.ui.container.Panel
        ControlModeSwitch               matlab.ui.control.Switch
        ControlModeSwitch_2Label        matlab.ui.control.Label
        PumpPowerSlider                 matlab.ui.control.Slider
        PumpPowerSlider_2Label          matlab.ui.control.Label
        PressureGraphPanel              matlab.ui.container.Panel
        PressureAxesWaitingForConnectionLabel  matlab.ui.control.Label
        PressureAxes                    matlab.ui.control.UIAxes
        FlowMonitoringControlPanel      matlab.ui.container.Panel
        Label_9                         matlab.ui.control.Label
        Flow2_LabelField                matlab.ui.control.EditField
        Flow1_LabelField                matlab.ui.control.EditField
        SensorLabel_2                   matlab.ui.control.Label
        GraphLabel_2                    matlab.ui.control.Label
        Flow1_GraphEnable               matlab.ui.control.CheckBox
        Flow2_GraphEnable               matlab.ui.control.CheckBox
        allvaluesinLminLabel            matlab.ui.control.Label
        Flow2_EditField_Current         matlab.ui.control.NumericEditField
        Label_4                         matlab.ui.control.Label
        Flow1_EditField_Current         matlab.ui.control.NumericEditField
        Label_3                         matlab.ui.control.Label
        TargetLabel_2                   matlab.ui.control.Label
        CurrentLabel_2                  matlab.ui.control.Label
        Flow2_SetPoint                  matlab.ui.control.NumericEditField
        Flow1_SetPoint                  matlab.ui.control.NumericEditField
        Label_2                         matlab.ui.control.Label
        PressureMonitoringControlPanel  matlab.ui.container.Panel
        Pressure3_LabelField            matlab.ui.control.EditField
        Pressure2_LabelField            matlab.ui.control.EditField
        Pressure1_LabelField            matlab.ui.control.EditField
        Label_8                         matlab.ui.control.Label
        Pressure1_GraphEnable           matlab.ui.control.CheckBox
        Pressure2_GraphEnable           matlab.ui.control.CheckBox
        Pressure3_GraphEnable           matlab.ui.control.CheckBox
        GraphLabel                      matlab.ui.control.Label
        SensorLabel                     matlab.ui.control.Label
        allvaluesinmmHgLabel            matlab.ui.control.Label
        Pressure3_EditField_Current     matlab.ui.control.NumericEditField
        Label_7                         matlab.ui.control.Label
        Pressure2_EditField_Current     matlab.ui.control.NumericEditField
        Label_6                         matlab.ui.control.Label
        Pressure1_EditField_Current     matlab.ui.control.NumericEditField
        Label_5                         matlab.ui.control.Label
        TargetLabel                     matlab.ui.control.Label
        CurrentLabel                    matlab.ui.control.Label
        Pressure3_SetPoint              matlab.ui.control.NumericEditField
        Pressure2_SetPoint              matlab.ui.control.NumericEditField
        Pressure1_SetPoint              matlab.ui.control.NumericEditField
        Label                           matlab.ui.control.Label
        SettingsOtherInfoTab            matlab.ui.container.Tab
        PIDCoefficientsPanel            matlab.ui.container.Panel
        FlowCoefficientsPanel           matlab.ui.container.Panel
        KdEditFieldLabel                matlab.ui.control.Label
        KdFlowEditField                 matlab.ui.control.NumericEditField
        KiFlowEditField                 matlab.ui.control.NumericEditField
        KiEditFieldLabel                matlab.ui.control.Label
        KpFlowEditField                 matlab.ui.control.NumericEditField
        KpEditFieldLabel                matlab.ui.control.Label
        FlowControlLabel                matlab.ui.control.Label
        PressureCoefficientsPanel       matlab.ui.container.Panel
        KdEditField_2Label              matlab.ui.control.Label
        KdPressureEditField             matlab.ui.control.NumericEditField
        KiPressureEditField             matlab.ui.control.NumericEditField
        KiEditField_2Label              matlab.ui.control.Label
        KpPressureEditField             matlab.ui.control.NumericEditField
        KpEditField_2Label              matlab.ui.control.Label
        PressureControlLabel            matlab.ui.control.Label
        PumpAnalogWriteValue0255EditField  matlab.ui.control.NumericEditField
        PumpAnalogWriteValue0255Label   matlab.ui.control.Label
        PressureValveControlNotAvailablePanel  matlab.ui.container.Panel
        ClosedSlider                    matlab.ui.control.Slider
        ClosedSliderLabel               matlab.ui.control.Label
        MotorStepNumberEditField        matlab.ui.control.NumericEditField
        MotorStepNumberEditFieldLabel   matlab.ui.control.Label
        SimulateDataCheckBox            matlab.ui.control.CheckBox
    end

    % TODO: Add "refresh ports" button

    properties (Access = private)
        plot_raw_data = true;
        F_REFRESH = 20;  % App refresh rate (Hz)
        ARDUINO_ANALOGWRITE_MAX = 255;
        ARDUINO_SERIAL_BITRATE = 115200;

        % Colors
        PRESSURE_COLOR = [0.84 0.83 0.86];
        FLOW_COLOR = [0.77,0.83,0.76];
        BUTTON_COLOR_DEFAULT = [0.94,0.94,0.94];
        BUTTON_COLOR1 = [0.16,0.57,0.84];
        COLOR_DISCONNECTED = [1.00,0.00,0.00];
        COLOR_CONNECTED = [0, 1, 0];

        appDir;

        arduinoObj;
        isConnected;
        tempLogFile = fullfile(pwd, 'console_output_temp.txt');  % Define a persistent temporary log file that always stores session history

        t = [];
        flow_vals1 = [];  % Containers for sensor data
        flow_vals2 = [];

        pressure_vals1 = [];
        pressure_vals2 = [];
        pressure_vals3 = [];

        % PID Variables
        DT_PID = 0.1;  % Time step (adjust based on system response)

        Kp_flow = 1;  % PID coefficients when controlling flow
        Ki_flow = 0;
        Kd_flow = 0;

        Kp_pressure = 2;  % PID coefficients when controlling pressure
        Ki_pressure = 0;
        Kd_pressure = 0;
        
        pressureMotorPosition = 0;
        MAX_PRESSURE_MOTOR_POSITION = -200*7;  % 7 rotations, 200 steps per rotation; TODO: read in max stepper value from Arduino instead of hard-coding to 7 rotations

        lastFlowDutyCycle = 128;  % for comparisons
        flowDutyCycle = 128;  % Initialize halfway
        PumpControlMode = 'Manual';

        PID_setpoint_id = "None";  % Identifier as to which sensor's setpoint is influencing PID control, since only one sensor at a time can do so
        PID_setpoint = 0;
        PID_output = 0;
        PID_prev_error = 0;
        PID_integral = 0;
        PID_derivative = 0;

        % Legend entry labels
        pressure1_label = "1";
        pressure2_label = "2";
        pressure3_label = "3";

        flow1_label = "1";
        flow2_label = "2";
    end

    properties (Dependent)
        dt_refresh
    end

    methods
        function value = get.dt_refresh(app)
            value = 1 / app.F_REFRESH;  % Compute refresh interval dynamically
        end
    end

    methods (Access = private)

        function [t, newFlowIndicator, flow_value1, ...
                flow_value2, pressure_value_raw1, ...
                pressure_value1, pressure_value_raw2, pressure_value2, ...
                pressure_value_raw3, pressure_value3] = parseArduinoData(app, line)

            % Reads and parses serial data from the Arduino serial port.
            % Inputs:
            %   serialObj - MATLAB serial object for Arduino communication.
            % Outputs:
            %   flow_value_raw - Raw ADC value for flow sensor.
            %   flow_value - Processed flow rate in L/min.
            %   pressure_value_raw - Raw ADC value for pressure sensor.
            %   pressure_value - Processed pressure in mmHg.

            t = toc;

            if isempty(line)
                disp("No serial data received...")
                newFlowIndicator = 'X';   % 'Y' or 'N'
                flow_value_raw1 = -999;
                flow_value1 = -999;
                flow_value_raw2 = -999;
                flow_value2 = -999;
                pressure_value_raw1 = -999;
                pressure_value1 = -999;
                pressure_value_raw2 = -999;
                pressure_value2 = -999;
                pressure_value_raw3 = -999;
                pressure_value3 = -999;
            end

            % Attempt to parse the serial data
            try
                % Ensure we received all expected values
                data = sscanf(line, ...
                    ['NF: %c, F1: %f L/min, F2: %f L/min, ' ...
                    'P1R: %d, P1: %f mmHg, ' ...
                    'P2R: %d, P2: %f mmHg, ' ...
                    'P3R: %d, P3: %f mmHg, ' ...
                    'Pump: %d/255\n']);

                % Ensure we received all expected values
                if numel(data) == 10
                    newFlowIndicator = char(data(1));  % 'Y' or 'N'
                    flow_value1 = data(2);
                    flow_value2 = data(3);
                    pressure_value_raw1 = data(4);
                    pressure_value1 = data(5);
                    pressure_value_raw2 = data(6);
                    pressure_value2 = data(7);
                    pressure_value_raw3 = data(8);
                    pressure_value3 = data(9);
                    pump_analog_write = data(10);

                    % Get timestamp
                    timestamp = datetime("now");

                    % Format the new sensor data as a string
                    newLogEntry = sprintf(['===== Sensor Data (%s) =====\n', ...
                        'NF: %c\n', ...
                        'F1: %.2f L/min, F2: %.2f L/min\n', ...
                        'P1R: %d, P1: %.2f mmHg\n', ...
                        'P2R: %d, P2: %.2f mmHg\n', ...
                        'P3R: %d, P3: %.2f mmHg\n', ...
                        'Pump: %d/255\n'], ...
                        timestamp, newFlowIndicator, ...
                        flow_value1, flow_value2, ...
                        pressure_value_raw1, pressure_value1, ...
                        pressure_value_raw2, pressure_value2, ...
                        pressure_value_raw3, pressure_value3, ...
                        pump_analog_write);

                    % Print to console
                    fprintf('%s', newLogEntry);
                else
                    warning('Failed to parse expected number of values from serial data.');
                    disp(line);
                end
            catch
                warning('Error reading and parsing serial data.');
                disp(line);
            end

        end

        function startLogging(app)
            % Start recording console output to the temporary file
            diary(app.tempLogFile);
            diary on;

            % Notify user
            fprintf('Logging started. All session logs will be recorded in: %s\n', app.tempLogFile);
        end

        function saveLog(app, filename)
            % Stop logging to the temporary file
            diary off;

            % Copy the temp log file to the new provided log file
            copyfile(app.tempLogFile, filename);

            % Notify user
            fprintf('Log file saved as: %s\n', filename);

            % Restart logging to the temporary file to maintain session history
            diary(app.tempLogFile);
            diary on;
        end

        
        function clearDataArrays(app)
            tic;
            app.t = [];

            app.flow_vals1 = [];  
            app.flow_vals2 = [];

            app.pressure_vals1 = [];
            app.pressure_vals2 = [];
            app.pressure_vals3 = [];
        end
       
        
        function runMainLoop(app)
            while (app.isConnected)
                line = app.getArduinoData();

                % Read sensor data
                [tNew, ~, flow_value1, flow_value2, ...
                    ~, pressure_value1, ~, pressure_value2, ...
                    ~, pressure_value3] = app.parseArduinoData(line);

                % Generate artificial values
                % tNew = 1;
                % flow_value_raw1 = -999;
                % flow_value1 = -999;
                % flow_value_raw2 = -999;
                % flow_value2 = -999;
                % pressure_value_raw1 = -999;
                % pressure_value1 = -999;
                % pressure_value_raw2 = -999;
                % pressure_value2 = -999;
                % pressure_value_raw3 = -999;
                % pressure_value3 = -999;

                % Append new sensor data
                app.t = [app.t tNew];
                app.flow_vals1 = [app.flow_vals1 flow_value1];
                app.flow_vals2 = [app.flow_vals2 flow_value2];

                app.pressure_vals1 = [app.pressure_vals1 pressure_value1];
                app.pressure_vals2 = [app.pressure_vals2 pressure_value2];
                app.pressure_vals3 = [app.pressure_vals3 pressure_value3];

                % Update Edit Fields with the latest sensor data
                app.Pressure1_EditField_Current.Value = pressure_value1;
                app.Pressure2_EditField_Current.Value = pressure_value2;
                app.Pressure3_EditField_Current.Value = pressure_value3;

                app.Flow1_EditField_Current.Value = flow_value1;
                app.Flow2_EditField_Current.Value = flow_value2;

                app.updateDataPlots();

                % Update PID Outputs
                if app.PumpControlMode == "Auto"
                    app.updatePIDOutputs();
                end

                % Check whether disconnect button has been clicked
                if app.ConnectButton.Text == "CONNECT"
                    break
                end

                % Pause to prevent crashes
                % pause(app.dt_refresh);
            end
        end

        function setControlMode(app,mode)
            if lower(mode) == "manual"
                app.PumpControlMode = "Manual";
                app.ControlModeSwitch.Value = "Manual";
                app.ControlModeSwitch.Enable = "off";  % to prevent user from switching toggle when they should just input a setpoint
                highlightSetPointUI(app, "None");
            elseif lower(mode) == "auto"
                app.PumpControlMode = "Auto";
                app.ControlModeSwitch.Value = "Auto";
                app.ControlModeSwitch.Enable = "on";
            else
                warning("Invalid control mode selected.");
            end
        end
        
        function sendControlValuesToArduino(app)
            app.MotorStepNumberEditField.Value = app.pressureMotorPosition;

            if lower(app.PumpControlMode) == "auto"
                % Update control values based on PID algorithm
                app.flowDutyCycle = app.flowDutyCycle + round(app.PID_output);

                % Place PID output within limits
                if app.flowDutyCycle > app.ARDUINO_ANALOGWRITE_MAX
                    app.flowDutyCycle = app.ARDUINO_ANALOGWRITE_MAX;
                elseif app.flowDutyCycle < 0
                    app.flowDutyCycle = 0;
                end
            elseif lower(app.PumpControlMode) == "manual"
                % do nothing
            else
                error("Invalid pump control mode selected.")
            end

            % Update flow pump control UI
            app.PumpAnalogWriteValue0255EditField.Value = app.flowDutyCycle;
            app.PumpPowerSlider.Value = app.flowDutyCycle / app.ARDUINO_ANALOGWRITE_MAX * app.PumpPowerSlider.Limits(2);

            % Send command to Arduino
            if app.SimulateDataCheckBox.Value == false
                command = sprintf("MOT: %d, PMP: %d",app.pressureMotorPosition,app.flowDutyCycle);
                writeline(app.arduinoObj,command);
                app.WaitForArduinoMessage();
            end
        end
        
        function updatePIDOutputs(app)
            % Choose appropriate PID value based on setpoint ID
            newValues = struct(...
            'Pressure1', app.pressure_vals1(end), ...
            'Pressure2', app.pressure_vals2(end), ...
            'Pressure3', app.pressure_vals3(end), ...
            'Flow1', app.flow_vals1(end), ...
            'Flow2', app.flow_vals2(end) ...
            );

            % Ensure PID_setpoint_id is a valid field name
            if isfield(newValues, app.PID_setpoint_id)
                newValue = newValues.(app.PID_setpoint_id); % Use dynamic field referencing
            else
                error('Invalid field name: %s', app.PID_setpoint_id);
            end

            % Use the correct PID coefficients based on setpoint ID
            if contains(app.PID_setpoint_id, 'Pressure', 'IgnoreCase', true)
                Kp = app.Kp_pressure;
                Ki = app.Ki_pressure;
                Kd = app.Kd_pressure;
            elseif contains(app.PID_setpoint_id, 'Flow', 'IgnoreCase', true)
                Kp = app.Kp_flow;
                Ki = app.Ki_flow;
                Kd = app.Kd_flow;
            else
                error('Unknown PID setpoint ID: %s', app.PID_setpoint_id);
            end

            % Calculate PID output
            PID_error = app.PID_setpoint - newValue;
            app.PID_integral = app.PID_integral + PID_error * app.DT_PID;
            app.PID_derivative = (PID_error - app.PID_prev_error) / app.DT_PID;
            app.PID_output = Kp * PID_error + Ki * app.PID_integral + Kd * app.PID_derivative;
            app.PID_prev_error = PID_error;

            % Send PID output to Arduino
            app.sendControlValuesToArduino();
        end
        
        function updateSerialPortList(app)
            % Get list of available serial ports
            ports = serialportlist;

            if isempty(ports)
                uialert(app.UIFigure, "Try refreshing the connections list or check your device connection.", "No serial ports detected")
                app.ConnectionDropDown.Enable = "off";
                return
            else
                app.ConnectionDropDown.Enable = "on";  % in case it was disabled before
            end

            % Read the "friendly names" of the COM Port devices using Powershell

            portInfo = {};

            % Use system command to get device descriptions (Windows)
            if ispc
                [~, cmdout] = system("powershell -Command ""Get-WMIObject Win32_PnPEntity | Where-Object { $_.Name -match '\(COM[0-9]+\)' } | ForEach-Object { $_.Name }""");
                deviceList = splitlines(strtrim(cmdout));
            else
                deviceList = repmat("Unknown Device", size(ports));
            end

            for i = 1:length(ports)
                matchIdx = find(contains(deviceList, ports(i)), 1);
                if ~isempty(matchIdx)
                    idn = deviceList{matchIdx};
                    idn = strtrim(erase(idn, ['(', ports(i), ')'])); % Strip duplicate COM port info from the name
                else
                    idn = "Unknown Device";
                end

                portInfo{i} = sprintf('%s: %s', ports(i), idn);
            end

            % Update dropdown with formatted port names
            app.ConnectionDropDown.Items = portInfo;
            app.ConnectionDropDown.ItemsData = ports;  % To allow proper connections when using serialport() later off of the selected dropdown value
        end
        
        function setValveControlPanelEnabled(app, value)
            % Enable/disable all pressure valve control panel child objects
            children = app.PressureValveControlNotAvailablePanel.Children;
            for i = 1:numel(children)
                if value == true
                    children(i).Enable = 'on';
                else
                    children(i).Enable = 'off';
                end
            end
        end
        
        function highlightSetPointUI(app, setpointID)
            % Define all setpoints in a struct for easy iteration
            setpoints = struct(...
            'Pressure1', app.Pressure1_SetPoint, ...
            'Pressure2', app.Pressure2_SetPoint, ...
            'Pressure3', app.Pressure3_SetPoint, ...
            'Flow1', app.Flow1_SetPoint, ...
            'Flow2', app.Flow2_SetPoint ...
            );

            % Iterate through all setpoints and update colors
            fieldNames = fieldnames(setpoints);

            for i = 1:length(fieldNames)
                % Get handle to the UI element
                uiElement = setpoints.(fieldNames{i});

                onColor = [0 1 0];
                offColor = [1 1 1];

                if strcmp(fieldNames{i}, setpointID)
                    if isequal(uiElement.BackgroundColor, onColor)
                        uiElement.BackgroundColor = offColor;
                        pause(0.2);  % if it's already green, flash it white briefly to show that a new value is being set
                        uiElement.BackgroundColor = onColor;
                    else
                        uiElement.BackgroundColor = onColor;
                    end
                else
                    uiElement.BackgroundColor = offColor;
                end
            end

        end

        
        function setUpArduinoConnection(app)
            app.arduinoObj = serialport(app.ConnectionDropDown.Value, app.ARDUINO_SERIAL_BITRATE);
            configureTerminator(app.arduinoObj, "LF"); % Assuming newline ('\n') terminates messages
            app.arduinoObj.Timeout = 5; % Timeout for read operations 

            app.WaitForArduinoMessage();
        end
        
        function line = getArduinoData(app)
            if app.SimulateDataCheckBox.Value
                % Simulate data in GUI (for testing)
                line = "NF: N, F1: 1.50 L/min, F2: 2.50 L/min, P1R: 1023, P1: 20 mmHg, P2R: 1023, P2: 50 mmHg, P3R: 1023, P3: 80 mmHg, Pump: 128/255\n";
            else
                if app.arduinoObj.NumBytesAvailable > 0
                    flushinput(app.arduinoObj);
                    readline(app.arduinoObj);  % Read until next newline in case flush clears part of a line
                    line = readline(app.arduinoObj); % Read a line of text
                else
                    line = [];
                end
            end

            pause(0.1); % Small delay to prevent CPU overuse
        end
        
        function WaitForArduinoMessage(app)
            while (app.arduinoObj.NumBytesAvailable == 0)
                pause(0.0001);
            end
        end
        
        function updateUIPIDCoeffs(app)
            % Update UI PID coefficient values
            app.KpFlowEditField.Value = app.Kp_flow;  % PID coefficients when controlling flow
            app.KiFlowEditField.Value = app.Ki_flow;
            app.KdFlowEditField.Value = app.Kd_flow;

            app.KpPressureEditField.Value = app.Kp_pressure;  % PID coefficients when controlling pressure
            app.KiPressureEditField.Value = app.Ki_pressure;
            app.KdPressureEditField.Value = app.Kd_pressure; 
        end
        
        function updateUIColors(app)
            % Color all pressure-related elements
            pressureElements = [app.PressureMonitoringControlPanel, ...
                app.PressureGraphPanel, ...
                app.PressureCoefficientsPanel];

            for pressureElement = pressureElements
                pressureElement.BackgroundColor = app.PRESSURE_COLOR;
            end

            % Color all flow-related elements
            flowElements = [app.FlowMonitoringControlPanel, ...
                app.FlowGraphPanel, ...
                app.FlowCoefficientsPanel];

            for flowElement = flowElements
                flowElement.BackgroundColor = app.FLOW_COLOR;
            end

            % Other things
            app.ConnectButton.BackgroundColor = app.COLOR_DISCONNECTED;

            app.GraphOptionsLabel.FontColor = app.BUTTON_COLOR1;
            graphOptionsElements = [app.ClearDataButton, app.ExportDataButton, app.PauseGraphsButton];

            for element = graphOptionsElements
                element.BackgroundColor = app.BUTTON_COLOR1;
            end

            app.PressureAxes.Title.Color = [0.5, 0.5, 0.5];
            app.FlowAxes.Title.Color = [0.5, 0.5, 0.5];
            
        end
        
        function connectGUI(app)
            % Someone just connected to the serial device

            % Loading bar
            dlg = uiprogressdlg(app.UIFigure, ...
                'Title', 'Loading...', ...
                'Message', 'Please wait...', ...
                'Indeterminate', 'on');

            % Update UI
            app.ConnectButton.Text = "Disconnect";
            app.ConnectButton.BackgroundColor = app.BUTTON_COLOR_DEFAULT;
            app.ConnectedLamp.Color = app.COLOR_CONNECTED;
            app.setGUIEnabled(true);

            if app.SimulateDataCheckBox.Value == false
                app.setUpArduinoConnection();
                app.setValveControlPanelEnabled(true);
            else
                app.setValveControlPanelEnabled(false);
            end

            % Perform other housekeeping
            app.clearDataArrays();
            app.startLogging();
            app.isConnected = true;

            close(dlg);
            tic;

            app.runMainLoop();
            
        end
        
        function disconnectGUI(app)
            % Loading bar
            dlg = uiprogressdlg(app.UIFigure, ...
                'Title', 'Disconnecting...', ...
                'Message', 'Please wait...', ...
                'Indeterminate', 'on');

            app.arduinoObj = [];
            app.setValveControlPanelEnabled(false);
            app.ConnectButton.Text = "Connect";
            app.ConnectButton.BackgroundColor = app.COLOR_DISCONNECTED;
            app.ConnectedLamp.Color = app.COLOR_DISCONNECTED;
            app.isConnected = false;
            app.setGUIEnabled(false);

            close(dlg);
        end

        function setGUIEnabled(app, state)
            % Gather all UI elements to enable/disable
            uiElements = [
                app.PressureMonitoringControlPanel;
                app.FlowMonitoringControlPanel;
                app.PumpControlPanel;
                app.PumpPowerSlider;
                app.PIDCoefficientsPanel;
                app.GraphOptionsPanel.Children(:)
                ];

            % Convert logical state to 'on'/'off' string
            stateStr = ternary(state, 'on', 'off');

            % Enable/Disable each UI element
            for uiElement = uiElements'
                if isprop(uiElement, 'Enable')
                    uiElement.Enable = stateStr;
                end
            end

            % Graph formatting based on state
            if state
                graphColor = [1 1 1];
                textColor = [0.15, 0.15, 0.15];  % Default text color
                toolbarVisible = true;
                waitingForConnectionLabelVisible = false;
                waitingText = 'Loading...';
            else
                graphColor = [0.90, 0.90, 0.90];
                textColor = [0.50, 0.50, 0.50];  % Disabled text color
                toolbarVisible = false;
                waitingForConnectionLabelVisible = true;
                waitingText = 'No connection';
            end

            % Clear graphs if disabling UI
            if ~state
                delete(findall(app.PressureAxes, 'Type', 'Line'));   % Remove lines
                legend(app.PressureAxes, 'off');                    % Remove the legend box

                delete(findall(app.FlowAxes, 'Type', 'Line'));
                legend(app.FlowAxes, 'off'); 
            end

            % Apply formatting to graphs
            for ax = [app.PressureAxes, app.FlowAxes]
                ax.Color = graphColor;
                ax.Title.Color = textColor;
                ax.YColor = textColor;
                ax.XColor = textColor;
                ax.Toolbar.Visible = toolbarVisible;
                axWaitingForConnectionLabel.Visible = waitingForConnectionLabelVisible;
                axWaitingForConnectionLabel.Text = waitingText;

                if state
                    enableDefaultInteractivity(ax);
                else
                    disableDefaultInteractivity(ax);
                end
            end

            pause(1);  % for smoother loading when enabling, and to ensure graphs get cleared when disabling
        end
        
        function updateDataPlots(app)
            % Plot data based on UI selections
            if app.PauseGraphsButton.Value == false && app.isConnected
                hold(app.PressureAxes, 'on');
                cla(app.PressureAxes); % Clear previous plots

                if strcmp(app.PressureAxesWaitingForConnectionLabel.Visible,'on')
                    % Hide "Waiting for Connection" labels on graphs if
                    % they're still visible (i.e. first time plotting)
                    app.PressureAxesWaitingForConnectionLabel.Visible = 'off';
                    app.FlowAxesWaitingForConnectionLabel.Visible = 'off';
                end

                lineWidth = 1.5;

                % Plot pressure data
                if app.Pressure1_GraphEnable.Value
                    plot(app.PressureAxes, app.t, app.pressure_vals1, '--', 'Color', app.PRESSURE_COLOR*0.6, 'DisplayName', app.pressure1_label, 'LineWidth', lineWidth);
                end
                if app.Pressure2_GraphEnable.Value
                    plot(app.PressureAxes, app.t, app.pressure_vals2, '-', 'Color', app.PRESSURE_COLOR*0.4, 'DisplayName', app.pressure2_label, 'LineWidth', lineWidth);
                end
                if app.Pressure3_GraphEnable.Value
                    plot(app.PressureAxes, app.t, app.pressure_vals3, '-.', 'Color', app.PRESSURE_COLOR*0.2, 'DisplayName', app.pressure3_label, 'LineWidth', lineWidth);
                end

                if contains(app.PID_setpoint_id,"Pressure")
                    plot(app.PressureAxes, app.t, ones(1,length(app.t))*app.PID_setpoint, '--r', 'DisplayName', 'Target', 'LineWidth', lineWidth)
                end

                hold(app.PressureAxes, 'off');
                title(app.PressureAxes, 'Pressure Data');
                xlabel(app.PressureAxes, 'Time (s)');
                ylabel(app.PressureAxes, 'Pressure (mmHg)');
                lgdPressure = legend(app.PressureAxes, 'show','Location','northwest');
                lgdPressure.FontSize = 8;

                % Determine appropriate y-limit
                pressureMax = max([app.pressure_vals1, app.pressure_vals2, app.pressure_vals3, app.PID_setpoint]);
                pressureMin = 0;
                pressureDefaultMax = 100;
                pressureMax = max(pressureDefaultMax, pressureMax * 1.1);  % Add 10% headroom
                ylim(app.PressureAxes, [pressureMin pressureMax]);

                % Plot flow data
                hold(app.FlowAxes, 'on');
                cla(app.FlowAxes); % Clear previous plots

                if app.Flow1_GraphEnable.Value
                    plot(app.FlowAxes, app.t, app.flow_vals1, '--', 'Color', app.FLOW_COLOR*0.6, 'DisplayName', app.flow1_label, 'LineWidth', lineWidth);
                end
                if app.Flow2_GraphEnable.Value
                    plot(app.FlowAxes, app.t, app.flow_vals2, '-', 'Color', app.FLOW_COLOR*0.4, 'DisplayName', app.flow2_label, 'LineWidth', lineWidth);
                end

                if contains(app.PID_setpoint_id,"Flow")
                    plot(app.FlowAxes, app.t, ones(1,length(app.t))*app.PID_setpoint, '--r', 'DisplayName', 'Target', 'LineWidth', lineWidth)
                end

                hold(app.FlowAxes, 'off');
                title(app.FlowAxes, 'Flow Data');
                xlabel(app.FlowAxes, 'Time (s)');
                ylabel(app.FlowAxes, 'Flow (L/min)');
                ylim(app.FlowAxes, [0 5]);
                lgdFlow = legend(app.FlowAxes, 'show','Location','northwest');
                lgdFlow.FontSize = 8;

                % Determine appropriate y-limit
                flowMax = max([app.flow_vals1, app.flow_vals2]);
                if contains(app.PID_setpoint_id, "Flow")
                    flowMax = max([flowMax, app.PID_setpoint]);  % if setpoint is for flow adjust y-limit appropriately
                end
                flowMin = 0;
                flowDefaultMax = 5;
                flowMax = max(flowDefaultMax, flowMax * 1.1);  % Add 10% headroom
                ylim(app.FlowAxes, [flowMin flowMax]);
            end

            pause(0.1);  % small pause to prevent CPU overuse
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.appDir = fileparts(mfilename('fullpath')); % Get app directory
            app.startLogging();
            app.updateSerialPortList();
            addpath(fullfile(app.appDir, 'helpers'));  % add helper functions to path
            app.MotorStepNumberEditField.Limits = [app.MAX_PRESSURE_MOTOR_POSITION 0];

            % Update UI elements
            app.UIFigure.Name = 'Cardiac Catheterization Testing Apparatus (CCTA)';
            disableDefaultInteractivity(app.PressureAxes);
            disableDefaultInteractivity(app.FlowAxes);
            app.updateUIColors();
            app.updateUIPIDCoeffs();
            app.PressureAxes.Visible = "on";  % bug sometimes makes it invisible
            app.FlowAxes.Visible = "on";  

            % Other setup
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            
            if app.ConnectButton.Text == "Connect"
                app.connectGUI();
            elseif app.ConnectButton.Text == "Disconnect"
                app.disconnectGUI();
            else
                error("Connect button container contains invalid text.")
            end
        end

        % Button pushed function: ClearDataButton
        function ClearDataButtonPushed(app, event)
            % Empty time and sensor data arrays
            app.clearDataArrays();
            app.runMainLoop();  % Re-run it here from the beginning to reset time array properly
        end

        % Button pushed function: ExportDataButton
        function ExportDataButtonPushed(app, event)
            dlg = uiprogressdlg(app.UIFigure, ...
                'Title', 'Exporting data...', ...
                'Message', 'Please wait...', ...
                'Indeterminate', 'on');

            % Get current timestamp
            timestamp = char(datetime('now', 'Format', 'yyyy-MM-dd-HH-mm-ss'));

            % Create "Data" subfolder if it doesn't exist
            dataFolder = fullfile(app.appDir, 'Data');
            if ~exist(dataFolder, 'dir')
                mkdir(dataFolder);
            end

            % Create the timestamped results folder inside "Data"
            folderName = fullfile(dataFolder, ['CCTA-', timestamp]);
            if ~exist(folderName, 'dir')
                mkdir(folderName);
            end
            
            % Save Pressure and Flow Axes as .fig and .png
            exportgraphics(app.PressureAxes, fullfile(folderName, 'PressurePlot.png'));
            exportgraphics(app.FlowAxes, fullfile(folderName, 'FlowPlot.png'));
            
            % Save data arrays to .mat/.csv files
            data.t = app.t;
            data.flow_vals1 = app.flow_vals1;
            data.flow_vals2 = app.flow_vals2;
            data.pressure_vals1 = app.pressure_vals1;
            data.pressure_vals2 = app.pressure_vals2;
            data.pressure_vals3 = app.pressure_vals3;
            
            save(fullfile(folderName, 'ExportedData.mat'), '-struct', 'data');

            csvData = table( ...
                app.t(:), ...
                app.flow_vals1(:), ...
                app.flow_vals2(:), ...
                app.pressure_vals1(:), ...
                app.pressure_vals2(:), ...
                app.pressure_vals3(:), ...
                'VariableNames', { ...
                'Time (s)', ...
                sprintf('Flow %s (L/min)', app.flow1_label), ...  % use labels from GUI to label the columns
                sprintf('Flow %s (L/min)', app.flow2_label), ...
                sprintf('Pressure %s (mmHg)', app.pressure1_label), ...
                sprintf('Pressure %s (mmHg)', app.pressure2_label), ...
                sprintf('Pressure %s (mmHg)', app.pressure3_label) ...
                } ...
                );
            
            writetable(csvData, fullfile(folderName, 'ExportedData.csv'));

            % Save console output to text file
            app.saveLog(fullfile(folderName, 'console_output.txt'));
            
            % Display dialog box
            uialert(app.UIFigure, ['Data has been saved to ', folderName], 'Export Successful', 'Icon', 'info');
            selection = uiconfirm(app.UIFigure, ...
                ['Data has been saved to ', folderName], ...
                'Export Successful', ...
                'Options', {'Open Folder', 'OK'}, ...
                'Icon', 'info');

            if strcmp(selection, 'Open Folder')
                % Open the folder in file explorer
                if ispc
                    winopen(folderName);
                elseif ismac
                    system(['open ', folderName]);
                else
                    system(['xdg-open ', folderName]);
                end
            end

            close(dlg);
        end

        % Value changed function: MotorStepNumberEditField
        function MotorStepNumberEditFieldValueChanged(app, event)
            value = app.MotorStepNumberEditField.Value;
            app.ClosedSlider.Value = value/app.MAX_PRESSURE_MOTOR_POSITION * app.ClosedSlider.Limits(2);  % update slider

            app.pressureMotorPosition = value;
            app.sendControlValuesToArduino();
        end

        % Value changed function: ClosedSlider
        function ClosedSliderValueChanged(app, event)
            percent_closed = app.ClosedSlider.Value/app.ClosedSlider.Limits(2);  % negative because gear rotates valve the other way
            app.pressureMotorPosition = round(app.MAX_PRESSURE_MOTOR_POSITION * percent_closed);

            highlightSetPointUI(app, "None");
            flushoutput(app.arduinoObj);  % in case this was done in the middle of sending another command
            app.sendControlValuesToArduino();
        end

        % Button pushed function: RefreshConnectionsButton
        function RefreshConnectionsButtonPushed(app, event)
            dlg = uiprogressdlg(app.UIFigure, ...
                'Title', 'Refreshing serial connections...', ...
                'Message', 'Please wait...', ...
                'Indeterminate', 'on');

            app.updateSerialPortList();

            close(dlg);
        end

        % Value changed function: PumpAnalogWriteValue0255EditField
        function PumpAnalogWriteValue0255EditFieldValueChanged(app, event)
            value = app.PumpAnalogWriteValue0255EditField.Value;
            app.flowDutyCycle = value;
            app.PumpPowerSlider.Value = value/app.ARDUINO_ANALOGWRITE_MAX * app.PumpPowerSlider.Limits(2);  % update slider
            app.setControlMode("Manual");
            app.sendControlValuesToArduino();
        end

        % Value changed function: Pressure1_LabelField
        function Pressure1_LabelFieldValueChanged(app, event)
            app.pressure1_label = app.Pressure1_LabelField.Value;
        end

        % Value changed function: Pressure2_LabelField
        function Pressure2_LabelFieldValueChanged(app, event)
            app.pressure2_label = app.Pressure2_LabelField.Value;
        end

        % Value changed function: Pressure3_LabelField
        function Pressure3_LabelFieldValueChanged(app, event)
            app.pressure3_label = app.Pressure3_LabelField.Value;
        end

        % Value changed function: Flow1_LabelField
        function Flow1_LabelFieldValueChanged(app, event)
            app.flow1_label = app.Flow1_LabelField.Value;
        end

        % Value changed function: Flow2_LabelField
        function Flow2_LabelFieldValueChanged(app, event)
            app.flow2_label = app.Flow2_LabelField.Value;
        end

        % Value changed function: Flow1_SetPoint
        function Flow1_SetPointValueChanged(app, event)
            app.PID_setpoint = app.Flow1_SetPoint.Value;
            app.setControlMode("Auto");
            app.highlightSetPointUI("Flow1");
            app.PID_setpoint_id = "Flow1";
        end

        % Value changed function: Flow2_SetPoint
        function Flow2_SetPointValueChanged(app, event)
            app.PID_setpoint = app.Flow2_SetPoint.Value;
            app.setControlMode("Auto");
            app.highlightSetPointUI("Flow2");
            app.PID_setpoint_id = "Flow2";
        end

        % Value changed function: Pressure1_SetPoint
        function Pressure1_SetPointValueChanged(app, event)
            app.PID_setpoint = app.Pressure1_SetPoint.Value;
            app.setControlMode("Auto");
            app.highlightSetPointUI("Pressure1");
            app.PID_setpoint_id = "Pressure1";
        end

        % Value changed function: Pressure2_SetPoint
        function Pressure2_SetPointValueChanged(app, event)
            app.PID_setpoint = app.Pressure2_SetPoint.Value;
            app.setControlMode("Auto");
            app.highlightSetPointUI("Pressure2");
            app.PID_setpoint_id = "Pressure2";
        end

        % Value changed function: Pressure3_SetPoint
        function Pressure3_SetPointValueChanged(app, event)
            app.PID_setpoint = app.Pressure3_SetPoint.Value;
            app.setControlMode("Auto");
            app.highlightSetPointUI("Pressure3");
            app.PID_setpoint_id = "Pressure3";
        end

        % Value changed function: KpFlowEditField
        function KpFlowEditFieldValueChanged(app, event)
            app.Kp_flow = app.KpFlowEditField.Value;
        end

        % Value changed function: KiFlowEditField
        function KiFlowEditFieldValueChanged(app, event)
            app.Ki_flow = app.KiFlowEditField.Value;
        end

        % Value changed function: KdFlowEditField
        function KdFlowEditFieldValueChanged(app, event)
            app.Kd_flow = app.KdFlowEditField.Value;
        end

        % Value changed function: KpPressureEditField
        function KpPressureEditFieldValueChanged(app, event)
            app.Kp_pressure = app.KpPressureEditField.Value;
        end

        % Value changed function: KiPressureEditField
        function KiPressureEditFieldValueChanged(app, event)
            app.Ki_pressure = app.KiPressureEditField.Value;
        end

        % Value changed function: KdPressureEditField
        function KdPressureEditFieldValueChanged(app, event)
            app.Kd_pressure = app.KdPressureEditField.Value;
        end

        % Value changed function: PumpPowerSlider
        function PumpPowerSliderValueChanged(app, event)
            app.flowDutyCycle = round(app.PumpPowerSlider.Value/app.PumpPowerSlider.Limits(2)*app.ARDUINO_ANALOGWRITE_MAX);
            app.PumpAnalogWriteValue0255EditField.Value = app.flowDutyCycle;
            app.setControlMode("Manual");

            flushoutput(app.arduinoObj);  % in case this was done in the middle of sending another command
            app.sendControlValuesToArduino();
        end

        % Value changed function: ControlModeSwitch
        function ControlModeSwitchValueChanged(app, event)
            value = app.ControlModeSwitch.Value;
            app.setControlMode(value);

            if value == "Manual"
                app.highlightSetPointUI("None");
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.MainTab = uitab(app.TabGroup2);
            app.MainTab.Title = 'Main';
            app.PressureMonitoringControlPanel = uipanel(app.MainTab);
            app.PressureMonitoringControlPanel.Enable = 'off';
            app.PressureMonitoringControlPanel.Title = 'Pressure Monitoring & Control';
            app.Label = uilabel(app.PressureMonitoringControlPanel);
            app.Label.Text = '';
            app.Pressure1_SetPoint = uieditfield(app.PressureMonitoringControlPanel, 'numeric');
            app.Pressure1_SetPoint.Limits = [0 Inf];
            app.Pressure1_SetPoint.ValueChangedFcn = createCallbackFcn(app, @Pressure1_SetPointValueChanged, true);
            app.Pressure2_SetPoint = uieditfield(app.PressureMonitoringControlPanel, 'numeric');
            app.Pressure2_SetPoint.Limits = [0 Inf];
            app.Pressure2_SetPoint.ValueChangedFcn = createCallbackFcn(app, @Pressure2_SetPointValueChanged, true);
            app.Pressure3_SetPoint = uieditfield(app.PressureMonitoringControlPanel, 'numeric');
            app.Pressure3_SetPoint.Limits = [0 Inf];
            app.Pressure3_SetPoint.ValueChangedFcn = createCallbackFcn(app, @Pressure3_SetPointValueChanged, true);
            app.CurrentLabel = uilabel(app.PressureMonitoringControlPanel);
            app.CurrentLabel.Text = 'Current';
            app.TargetLabel = uilabel(app.PressureMonitoringControlPanel);
            app.TargetLabel.Text = 'Target';
            app.Label_5 = uilabel(app.PressureMonitoringControlPanel);
            app.Label_5.Text = '1';
            app.Pressure1_EditField_Current = uieditfield(app.PressureMonitoringControlPanel, 'numeric');
            app.Pressure1_EditField_Current.Editable = 'off';
            app.Label_6 = uilabel(app.PressureMonitoringControlPanel);
            app.Label_6.Text = '2';
            app.Pressure2_EditField_Current = uieditfield(app.PressureMonitoringControlPanel, 'numeric');
            app.Pressure2_EditField_Current.Editable = 'off';
            app.Label_7 = uilabel(app.PressureMonitoringControlPanel);
            app.Label_7.Text = '3';
            app.Pressure3_EditField_Current = uieditfield(app.PressureMonitoringControlPanel, 'numeric');
            app.Pressure3_EditField_Current.Editable = 'off';
            app.allvaluesinmmHgLabel = uilabel(app.PressureMonitoringControlPanel);
            app.allvaluesinmmHgLabel.Enable = 'off';
            app.allvaluesinmmHgLabel.Text = '(all values in mmHg)';
            app.SensorLabel = uilabel(app.PressureMonitoringControlPanel);
            app.SensorLabel.Text = 'Sensor';
            app.GraphLabel = uilabel(app.PressureMonitoringControlPanel);
            app.GraphLabel.Text = 'Graph?';
            app.Pressure3_GraphEnable = uicheckbox(app.PressureMonitoringControlPanel);
            app.Pressure3_GraphEnable.Text = '';
            app.Pressure3_GraphEnable.Value = true;
            app.Pressure2_GraphEnable = uicheckbox(app.PressureMonitoringControlPanel);
            app.Pressure2_GraphEnable.Text = '';
            app.Pressure2_GraphEnable.Value = true;
            app.Pressure1_GraphEnable = uicheckbox(app.PressureMonitoringControlPanel);
            app.Pressure1_GraphEnable.Text = '';
            app.Pressure1_GraphEnable.Value = true;
            app.Label_8 = uilabel(app.PressureMonitoringControlPanel);
            app.Pressure1_LabelField = uieditfield(app.PressureMonitoringControlPanel, 'text');
            app.Pressure1_LabelField.ValueChangedFcn = createCallbackFcn(app, @Pressure1_LabelFieldValueChanged, true);
            app.Pressure1_LabelField.Value = '1';
            app.Pressure2_LabelField = uieditfield(app.PressureMonitoringControlPanel, 'text');
            app.Pressure2_LabelField.ValueChangedFcn = createCallbackFcn(app, @Pressure2_LabelFieldValueChanged, true);
            app.Pressure2_LabelField.Value = '2';
            app.Pressure3_LabelField = uieditfield(app.PressureMonitoringControlPanel, 'text');
            app.Pressure3_LabelField.ValueChangedFcn = createCallbackFcn(app, @Pressure3_LabelFieldValueChanged, true);
            app.Pressure3_LabelField.Value = '3';
            app.FlowMonitoringControlPanel = uipanel(app.MainTab);
            app.FlowMonitoringControlPanel.Enable = 'off';
            app.FlowMonitoringControlPanel.Title = 'Flow Monitoring & Control';
            app.Label_2 = uilabel(app.FlowMonitoringControlPanel);
            app.Label_2.Text = '';
            app.Flow1_SetPoint = uieditfield(app.FlowMonitoringControlPanel, 'numeric');
            app.Flow1_SetPoint.Limits = [0 Inf];
            app.Flow1_SetPoint.ValueChangedFcn = createCallbackFcn(app, @Flow1_SetPointValueChanged, true);
            app.Flow2_SetPoint = uieditfield(app.FlowMonitoringControlPanel, 'numeric');
            app.Flow2_SetPoint.Limits = [0 Inf];
            app.Flow2_SetPoint.ValueChangedFcn = createCallbackFcn(app, @Flow2_SetPointValueChanged, true);
            app.CurrentLabel_2 = uilabel(app.FlowMonitoringControlPanel);
            app.CurrentLabel_2.Text = 'Current';
            app.TargetLabel_2 = uilabel(app.FlowMonitoringControlPanel);
            app.TargetLabel_2.Text = 'Target';
            app.Label_3 = uilabel(app.FlowMonitoringControlPanel);
            app.Label_3.Text = '1';
            app.Flow1_EditField_Current = uieditfield(app.FlowMonitoringControlPanel, 'numeric');
            app.Flow1_EditField_Current.Editable = 'off';
            app.Label_4 = uilabel(app.FlowMonitoringControlPanel);
            app.Label_4.Text = '2';
            app.Flow2_EditField_Current = uieditfield(app.FlowMonitoringControlPanel, 'numeric');
            app.Flow2_EditField_Current.Editable = 'off';
            app.allvaluesinLminLabel = uilabel(app.FlowMonitoringControlPanel);
            app.allvaluesinLminLabel.Text = '(all values in L/min)';
            app.Flow2_GraphEnable = uicheckbox(app.FlowMonitoringControlPanel);
            app.Flow2_GraphEnable.Text = '';
            app.Flow2_GraphEnable.Value = true;
            app.Flow1_GraphEnable = uicheckbox(app.FlowMonitoringControlPanel);
            app.Flow1_GraphEnable.Text = '';
            app.Flow1_GraphEnable.Value = true;
            app.GraphLabel_2 = uilabel(app.FlowMonitoringControlPanel);
            app.GraphLabel_2.Text = 'Graph?';
            app.SensorLabel_2 = uilabel(app.FlowMonitoringControlPanel);
            app.SensorLabel_2.Text = 'Sensor';
            app.Flow1_LabelField = uieditfield(app.FlowMonitoringControlPanel, 'text');
            app.Flow1_LabelField.ValueChangedFcn = createCallbackFcn(app, @Flow1_LabelFieldValueChanged, true);
            app.Flow1_LabelField.Value = '1';
            app.Flow2_LabelField = uieditfield(app.FlowMonitoringControlPanel, 'text');
            app.Flow2_LabelField.ValueChangedFcn = createCallbackFcn(app, @Flow2_LabelFieldValueChanged, true);
            app.Flow2_LabelField.Value = '2';
            app.Label_9 = uilabel(app.FlowMonitoringControlPanel);
            app.PressureGraphPanel = uipanel(app.MainTab);
            app.PressureAxes = uiaxes(app.PressureGraphPanel);
            title(app.PressureAxes, 'Pressure Data')
            xlabel(app.PressureAxes, 'Time (s)')
            ylabel(app.PressureAxes, 'Pressure (mmHg)')
            zlabel(app.PressureAxes, 'Z')
            app.PressureAxes.Visible = 'off';
            app.PressureAxesWaitingForConnectionLabel = uilabel(app.PressureGraphPanel);
            app.PressureAxesWaitingForConnectionLabel.Enable = 'off';
            app.PressureAxesWaitingForConnectionLabel.Text = 'No connection';
            app.PumpControlPanel = uipanel(app.MainTab);
            app.PumpControlPanel.Enable = 'off';
            app.PumpControlPanel.Title = 'Pump Control';
            app.PumpPowerSlider_2Label = uilabel(app.PumpControlPanel);
            app.PumpPowerSlider_2Label.Text = 'Pump Power  %';
            app.PumpPowerSlider = uislider(app.PumpControlPanel);
            app.PumpPowerSlider.ValueChangedFcn = createCallbackFcn(app, @PumpPowerSliderValueChanged, true);
            app.ControlModeSwitch_2Label = uilabel(app.PumpControlPanel);
            app.ControlModeSwitch_2Label.Text = 'Control Mode';
            app.ControlModeSwitch = uiswitch(app.PumpControlPanel, 'slider');
            app.ControlModeSwitch.Items = {'Manual', 'Auto'};
            app.ControlModeSwitch.ValueChangedFcn = createCallbackFcn(app, @ControlModeSwitchValueChanged, true);
            app.ControlModeSwitch.Tooltip = {'Manual - control pump power using the slider below'; ''; 'Auto - Input a flow or puressure setpoint in the panels above and the system will automatically adjust'};
            app.ControlModeSwitch.Value = 'Manual';
            app.FlowGraphPanel = uipanel(app.MainTab);
            app.FlowAxes = uiaxes(app.FlowGraphPanel);
            title(app.FlowAxes, 'Flow Data')
            xlabel(app.FlowAxes, 'Time(s)')
            ylabel(app.FlowAxes, 'Flow (L/min)')
            zlabel(app.FlowAxes, 'Z')
            app.FlowAxesWaitingForConnectionLabel = uilabel(app.FlowGraphPanel);
            app.FlowAxesWaitingForConnectionLabel.Enable = 'off';
            app.FlowAxesWaitingForConnectionLabel.Text = 'No connection';
            app.Panel_5 = uipanel(app.MainTab);
            app.ConnectButton = uibutton(app.Panel_5, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.RefreshConnectionsButton = uibutton(app.Panel_5, 'push');
            app.RefreshConnectionsButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshConnectionsButtonPushed, true);
            app.RefreshConnectionsButton.Icon = fullfile(pathToMLAPP, 'assets', 'Refresh_icon.png');
            app.RefreshConnectionsButton.Text = '';
            app.ConnectedLamp = uilamp(app.Panel_5);
            app.COMPortLabel = uilabel(app.Panel_5);
            app.COMPortLabel.Text = 'Connection';
            app.ConnectionDropDown = uidropdown(app.Panel_5);
            app.ConnectionDropDown.Items = {};
            app.ConnectionDropDown.Value = {};
            app.GraphOptionsPanel = uipanel(app.MainTab);
            app.PauseGraphsButton = uibutton(app.GraphOptionsPanel, 'state');
            app.PauseGraphsButton.Enable = 'off';
            app.PauseGraphsButton.Icon = fullfile(pathToMLAPP, 'assets', 'pause_icon.png');
            app.PauseGraphsButton.Text = 'Pause Graphs';
            app.ClearDataButton = uibutton(app.GraphOptionsPanel, 'push');
            app.ClearDataButton.ButtonPushedFcn = createCallbackFcn(app, @ClearDataButtonPushed, true);
            app.ClearDataButton.Icon = fullfile(pathToMLAPP, 'assets', 'eraser_white.png');
            app.ClearDataButton.Enable = 'off';
            app.ClearDataButton.Text = 'Clear Data';
            app.ExportDataButton = uibutton(app.GraphOptionsPanel, 'push');
            app.ExportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDataButtonPushed, true);
            app.ExportDataButton.Icon = fullfile(pathToMLAPP, 'assets', 'export.png');
            app.ExportDataButton.Enable = 'off';
            app.ExportDataButton.Text = 'Export Data';
            app.GraphOptionsLabel = uilabel(app.GraphOptionsPanel);
            app.GraphOptionsLabel.Enable = 'off';
            app.GraphOptionsLabel.Text = 'Graph Options';
            app.SettingsOtherInfoTab = uitab(app.TabGroup2);
            app.SettingsOtherInfoTab.Title = 'Settings & Other Info.';
            app.SimulateDataCheckBox = uicheckbox(app.SettingsOtherInfoTab);
            app.SimulateDataCheckBox.Text = 'Simulate Data?';
            app.PressureValveControlNotAvailablePanel = uipanel(app.SettingsOtherInfoTab);
            app.PressureValveControlNotAvailablePanel.Title = 'Pressure Valve Control (Not Available)';
            app.MotorStepNumberEditFieldLabel = uilabel(app.PressureValveControlNotAvailablePanel);
            app.MotorStepNumberEditFieldLabel.Enable = 'off';
            app.MotorStepNumberEditFieldLabel.Text = 'Motor Step Number';
            app.MotorStepNumberEditField = uieditfield(app.PressureValveControlNotAvailablePanel, 'numeric');
            app.MotorStepNumberEditField.Limits = [0 200];
            app.MotorStepNumberEditField.RoundFractionalValues = 'on';
            app.MotorStepNumberEditField.ValueChangedFcn = createCallbackFcn(app, @MotorStepNumberEditFieldValueChanged, true);
            app.MotorStepNumberEditField.Enable = 'off';
            app.ClosedSliderLabel = uilabel(app.PressureValveControlNotAvailablePanel);
            app.ClosedSliderLabel.Enable = 'off';
            app.ClosedSliderLabel.Text = '% Closed';
            app.ClosedSlider = uislider(app.PressureValveControlNotAvailablePanel);
            app.ClosedSlider.ValueChangedFcn = createCallbackFcn(app, @ClosedSliderValueChanged, true);
            app.ClosedSlider.Enable = 'off';
            app.PumpAnalogWriteValue0255Label = uilabel(app.SettingsOtherInfoTab);
            app.PumpAnalogWriteValue0255Label.Text = {'Pump Analog Write Value '; '(0-255)'};
            app.PumpAnalogWriteValue0255EditField = uieditfield(app.SettingsOtherInfoTab, 'numeric');
            app.PumpAnalogWriteValue0255EditField.Limits = [0 255];
            app.PumpAnalogWriteValue0255EditField.RoundFractionalValues = 'on';
            app.PumpAnalogWriteValue0255EditField.ValueChangedFcn = createCallbackFcn(app, @PumpAnalogWriteValue0255EditFieldValueChanged, true);
            app.PumpAnalogWriteValue0255EditField.Editable = 'off';
            app.PIDCoefficientsPanel = uipanel(app.SettingsOtherInfoTab);
            app.PIDCoefficientsPanel.Enable = 'off';
            app.PIDCoefficientsPanel.Title = 'PID Coefficients';
            app.PressureCoefficientsPanel = uipanel(app.PIDCoefficientsPanel);
            app.PressureControlLabel = uilabel(app.PressureCoefficientsPanel);
            app.PressureControlLabel.Text = 'Pressure Control';
            app.KpEditField_2Label = uilabel(app.PressureCoefficientsPanel);
            app.KpEditField_2Label.Text = 'Kp';
            app.KpPressureEditField = uieditfield(app.PressureCoefficientsPanel, 'numeric');
            app.KpPressureEditField.ValueChangedFcn = createCallbackFcn(app, @KpPressureEditFieldValueChanged, true);
            app.KiEditField_2Label = uilabel(app.PressureCoefficientsPanel);
            app.KiEditField_2Label.Text = 'Ki';
            app.KiPressureEditField = uieditfield(app.PressureCoefficientsPanel, 'numeric');
            app.KiPressureEditField.ValueChangedFcn = createCallbackFcn(app, @KiPressureEditFieldValueChanged, true);
            app.KdPressureEditField = uieditfield(app.PressureCoefficientsPanel, 'numeric');
            app.KdPressureEditField.ValueChangedFcn = createCallbackFcn(app, @KdPressureEditFieldValueChanged, true);
            app.KdEditField_2Label = uilabel(app.PressureCoefficientsPanel);
            app.KdEditField_2Label.Text = 'Kd';
            app.FlowCoefficientsPanel = uipanel(app.PIDCoefficientsPanel);
            app.FlowControlLabel = uilabel(app.FlowCoefficientsPanel);
            app.FlowControlLabel.Text = 'Flow Control';
            app.KpEditFieldLabel = uilabel(app.FlowCoefficientsPanel);
            app.KpEditFieldLabel.Text = 'Kp';
            app.KpFlowEditField = uieditfield(app.FlowCoefficientsPanel, 'numeric');
            app.KpFlowEditField.ValueChangedFcn = createCallbackFcn(app, @KpFlowEditFieldValueChanged, true);
            app.KiEditFieldLabel = uilabel(app.FlowCoefficientsPanel);
            app.KiEditFieldLabel.Text = 'Ki';
            app.KiFlowEditField = uieditfield(app.FlowCoefficientsPanel, 'numeric');
            app.KiFlowEditField.ValueChangedFcn = createCallbackFcn(app, @KiFlowEditFieldValueChanged, true);
            app.KdFlowEditField = uieditfield(app.FlowCoefficientsPanel, 'numeric');
            app.KdFlowEditField.ValueChangedFcn = createCallbackFcn(app, @KdFlowEditFieldValueChanged, true);
            app.KdEditFieldLabel = uilabel(app.FlowCoefficientsPanel);
            app.KdEditFieldLabel.Text = 'Kd';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CCTA

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end